classdef AfinadorDeGuitarra < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        IniciarButton          matlab.ui.control.Button
        Label                  matlab.ui.control.Label
        CuerdaaafinarDropDown  matlab.ui.control.DropDown
        CuerdaaafinarLabel     matlab.ui.control.Label
        EstadoLabel            matlab.ui.control.Label
        FrecuenciaActualLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: IniciarButton
        function IniciarButtonPushed(app, event)
            mode = app.CuerdaaafinarDropDown.Value;
            switch mode
                case 'Primera'
                    x = -5;
                case 'Segunda'
                    x = -10;
                case 'Tercera'
                    x = -14;
                case 'Cuarta'
                    x = -19;
                case 'Quinta'
                    x = -24;
                case 'Sexta'
                    x = -29;
            end
            fs = 2000;
            recObj = audiorecorder(fs, 16, 1);
            recordblocking(recObj, 2);
            y = getaudiodata(recObj);
            F1 = 440*2^((x-1)/12);
            F2 = 440*2^((x+1)/12);
            wp1 = F1/(fs/2);
            wp2 = F2/(fs/2);
            Wp= [wp1 wp2];
            [b, a] = butter(4,Wp,'bandpass');
            y1= filter(b,a,y);
            
            L = 0.1*fs;
            overlap = floor(0.9*L);
            nfft = 2^nextpow2(L);
            [pxx,f] = pwelch(y1,hamming(L),overlap,nfft,fs);
            [pks, locs] = findpeaks(pxx,'SortStr','descend','NPeaks',1);
            pk= f(locs);
            
            pk1 = string(pk);
            app.FrecuenciaActualLabel.Text = "Frecuencia Actual: " + pk1;
            order1 = 30 + 12 * log2(pk/440);
            order2 = round(order1);
            if order2 < 46
                n = order2 - order1;
                if n< - 0.35 
                    app.EstadoLabel.Text = "Estado: " +  "Por encima de la frecuencia";
                elseif n > 0.35
                    app.EstadoLabel.Text = "Estado: " +  "Por debajo de la frecuencia";
                else
                    app.EstadoLabel.Text = "Estado: " +  "En rango";
                end
            else
                app.EstadoLabel.Text = "Estado: " +  "Intente de nuevo";
            end 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FrecuenciaActualLabel = uilabel(app.UIFigure);
            app.FrecuenciaActualLabel.Text = 'Frecuencia Actual:';
            app.EstadoLabel = uilabel(app.UIFigure);
            app.EstadoLabel.Text = 'Estado:';
            app.CuerdaaafinarLabel = uilabel(app.UIFigure);
            app.CuerdaaafinarLabel.Text = 'Cuerda a afinar*';
            app.CuerdaaafinarDropDown = uidropdown(app.UIFigure);
            app.CuerdaaafinarDropDown.Items = {'Primera', 'Segunda', 'Tercera', 'Cuarta', 'Quinta', 'Sexta'};
            app.CuerdaaafinarDropDown.Value = 'Primera';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '*Se considera la Primera cuerda como la de sonido mas agudo, siendo la Sexta la mas grave.';
            app.IniciarButton = uibutton(app.UIFigure, 'push');
            app.IniciarButton.ButtonPushedFcn = createCallbackFcn(app, @IniciarButtonPushed, true);
            app.IniciarButton.Text = 'Iniciar';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AfinadorDeGuitarra

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end